classdef aperture < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ApertureUIFigure               matlab.ui.Figure
        Panel                          matlab.ui.container.Panel
        SizeEditFieldLabel             matlab.ui.control.Label
        SizeEditField                  matlab.ui.control.EditField
        NameEditFieldLabel             matlab.ui.control.Label
        NameEditField                  matlab.ui.control.EditField
        ShapeDropDownLabel             matlab.ui.control.Label
        ShapeDropDown                  matlab.ui.control.DropDown
        Size1SpinnerLabel              matlab.ui.control.Label
        Size1Spinner                   matlab.ui.control.Spinner
        Size2SpinnerLabel              matlab.ui.control.Label
        Size2Spinner                   matlab.ui.control.Spinner
        AngledegSpinnerLabel           matlab.ui.control.Label
        AngledegSpinner                matlab.ui.control.Spinner
        CentreEditFieldLabel           matlab.ui.control.Label
        CentreEditField                matlab.ui.control.EditField
        AspectSpinnerLabel             matlab.ui.control.Label
        AspectSpinner                  matlab.ui.control.Spinner
        CentrerotEditFieldLabel        matlab.ui.control.Label
        CentrerotEditField             matlab.ui.control.EditField
        ValuesEditFieldLabel           matlab.ui.control.Label
        ValuesEditField                matlab.ui.control.EditField
        UIAxes                         matlab.ui.control.UIAxes
        UpdateDeviceCheckBox           matlab.ui.control.CheckBox
        EnableDisplayCheckBox          matlab.ui.control.CheckBox
        DisplayDropDownLabel           matlab.ui.control.Label
        DisplayDropDown                matlab.ui.control.DropDown
        DisplayVariableEditFieldLabel  matlab.ui.control.Label
        DisplayVariableEditField       matlab.ui.control.EditField
        DeviceDropDownLabel            matlab.ui.control.Label
        DeviceDropDown                 matlab.ui.control.DropDown
    end


    methods (Access = public)
    
        function updateView(app)
            % Redraw the main display
            app.patternValueChanged([]);
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Populate the display device field
            otslm.ui.support.populateDeviceList(app.DeviceDropDown);
            
            % Generate the initial pattern
            app.patternValueChanged([]);
        end

        % Value changed function: AngledegSpinner, AspectSpinner, 
        % CentreEditField, CentrerotEditField, DeviceDropDown, 
        % DisplayDropDown, DisplayVariableEditField, 
        % EnableDisplayCheckBox, NameEditField, Size1Spinner, 
        % Size2Spinner, SizeEditField, UpdateDeviceCheckBox, 
        % ValuesEditField
        function patternValueChanged(app, event)
            
            % Get the UI fileds for generating the pattern
            name = app.NameEditField.Value;
            sz = evalin('base', ['[', app.SizeEditField.Value, ']']);
            dim1 = app.Size1Spinner.Value;
            dim2 = app.Size2Spinner.Value;
            angle_deg = app.AngledegSpinner.Value;
            aspect = app.AspectSpinner.Value;
            shape = app.ShapeDropDown.Value;
            values = evalin('base', ['[', app.ValuesEditField.Value, ']']);
            centre = evalin('base', ['[', app.CentreEditField.Value, ']']);
            offset = evalin('base', ['[', app.CentrerotEditField.Value, ']']);
            
            % Combine two spacing values
            if strcmpi(app.Size2Spinner.Enable, 'on')
                dim1 = [dim1, dim2];
            end
            
            % Generate the pattern
            pattern = otslm.simple.aperture(sz, dim1, ...
                'centre', centre, 'angle_deg', angle_deg, ...
                'offset', offset, 'aspect', aspect, 'shape', shape, ...
                'value', values);
            
            % Offload to the base class (sort of...)
            otslm.ui.support.simplePatternValueChanged(name, pattern, ...
                app.DeviceDropDown.Value, app.UpdateDeviceCheckBox.Value, ...
                app.EnableDisplayCheckBox.Value, app.UIAxes, ...
                app.DisplayDropDown.Value, app.DisplayVariableEditField.Value);
            
        end

        % Value changed function: ShapeDropDown
        function ShapeDropDownValueChanged(app, event)

            % Enable additional spinner for some aperture shapes
            switch app.ShapeDropDown.Value
                case 'circle'
                    app.Size2Spinner.Enable = false;
                case 'square'
                    app.Size2Spinner.Enable = false;
                case 'rect'
                    app.Size2Spinner.Enable = true;
                case 'ring'
                    app.Size2Spinner.Enable = true;
                otherwise
                    error('Invalid option for drop down box');
            end
            
            % Continue processing as usual
            app.patternValueChanged(event);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ApertureUIFigure = uifigure;
            app.ApertureUIFigure.Name = 'Aperture';
            app.Panel = uipanel(app.ApertureUIFigure);
            app.SizeEditFieldLabel = uilabel(app.Panel);
            app.SizeEditFieldLabel.Text = 'Size';
            app.SizeEditField = uieditfield(app.Panel, 'text');
            app.SizeEditField.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.SizeEditField.Value = '256,256';
            app.NameEditFieldLabel = uilabel(app.Panel);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.Panel, 'text');
            app.NameEditField.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.ShapeDropDownLabel = uilabel(app.Panel);
            app.ShapeDropDownLabel.Text = 'Shape';
            app.ShapeDropDown = uidropdown(app.Panel);
            app.ShapeDropDown.Items = {'Circle', 'Square', 'Rectangle', 'Ring'};
            app.ShapeDropDown.ItemsData = {'circle', 'square', 'rect', 'ring'};
            app.ShapeDropDown.ValueChangedFcn = createCallbackFcn(app, @ShapeDropDownValueChanged, true);
            app.ShapeDropDown.Value = 'circle';
            app.Size1SpinnerLabel = uilabel(app.Panel);
            app.Size1SpinnerLabel.Text = 'Size 1';
            app.Size1Spinner = uispinner(app.Panel);
            app.Size1Spinner.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.Size1Spinner.Value = 10;
            app.Size2SpinnerLabel = uilabel(app.Panel);
            app.Size2SpinnerLabel.Enable = 'off';
            app.Size2SpinnerLabel.Text = 'Size 2';
            app.Size2Spinner = uispinner(app.Panel);
            app.Size2Spinner.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.Size2Spinner.Enable = 'off';
            app.Size2Spinner.Value = 10;
            app.AngledegSpinnerLabel = uilabel(app.Panel);
            app.AngledegSpinnerLabel.Text = 'Angle (deg)';
            app.AngledegSpinner = uispinner(app.Panel);
            app.AngledegSpinner.Step = 5;
            app.AngledegSpinner.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.CentreEditFieldLabel = uilabel(app.Panel);
            app.CentreEditFieldLabel.Text = 'Centre';
            app.CentreEditField = uieditfield(app.Panel, 'text');
            app.CentreEditField.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.CentreEditField.Value = '128, 128';
            app.AspectSpinnerLabel = uilabel(app.Panel);
            app.AspectSpinnerLabel.Text = 'Aspect';
            app.AspectSpinner = uispinner(app.Panel);
            app.AspectSpinner.Step = 0.1;
            app.AspectSpinner.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.AspectSpinner.Value = 1;
            app.CentrerotEditFieldLabel = uilabel(app.Panel);
            app.CentrerotEditFieldLabel.Text = 'Centre (rot)';
            app.CentrerotEditField = uieditfield(app.Panel, 'text');
            app.CentrerotEditField.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.CentrerotEditField.Value = '0,0';
            app.ValuesEditFieldLabel = uilabel(app.Panel);
            app.ValuesEditFieldLabel.Text = 'Values';
            app.ValuesEditField = uieditfield(app.Panel, 'text');
            app.ValuesEditField.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.ValuesEditField.Value = '0, 0.5';
            app.UIAxes = uiaxes(app.ApertureUIFigure);
            app.UpdateDeviceCheckBox = uicheckbox(app.ApertureUIFigure);
            app.UpdateDeviceCheckBox.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.UpdateDeviceCheckBox.Text = 'Update Device';
            app.EnableDisplayCheckBox = uicheckbox(app.ApertureUIFigure);
            app.EnableDisplayCheckBox.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.EnableDisplayCheckBox.Text = 'Enable Display';
            app.EnableDisplayCheckBox.Value = true;
            app.DisplayDropDownLabel = uilabel(app.ApertureUIFigure);
            app.DisplayDropDownLabel.Text = 'Display';
            app.DisplayDropDown = uidropdown(app.ApertureUIFigure);
            app.DisplayDropDown.Items = {'Phase mask', 'Device image', 'Simulated farfield', 'Raw phase mask'};
            app.DisplayDropDown.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.DisplayDropDown.Value = 'Phase mask';
            app.DisplayVariableEditFieldLabel = uilabel(app.ApertureUIFigure);
            app.DisplayVariableEditFieldLabel.Text = 'Display Variable';
            app.DisplayVariableEditField = uieditfield(app.ApertureUIFigure, 'text');
            app.DisplayVariableEditField.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.DeviceDropDownLabel = uilabel(app.ApertureUIFigure);
            app.DeviceDropDownLabel.Text = 'Device';
            app.DeviceDropDown = uidropdown(app.ApertureUIFigure);
            app.DeviceDropDown.Items = {};
            app.DeviceDropDown.Editable = 'on';
            app.DeviceDropDown.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.DeviceDropDown.Value = {};
        end
    end

    methods (Access = public)

        % Construct app
        function app = aperture

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ApertureUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ApertureUIFigure)
        end
    end
end