classdef igmode < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        InceGaussianUIFigure           matlab.ui.Figure
        Panel                          matlab.ui.container.Panel
        SizeEditFieldLabel             matlab.ui.control.Label
        SizeEditField                  matlab.ui.control.EditField
        NameEditFieldLabel             matlab.ui.control.Label
        NameEditField                  matlab.ui.control.EditField
        OutputTypeDropDownLabel        matlab.ui.control.Label
        OutputTypeDropDown             matlab.ui.control.DropDown
        AngledegSpinnerLabel           matlab.ui.control.Label
        AngledegSpinner                matlab.ui.control.Spinner
        pmodeSpinnerLabel              matlab.ui.control.Label
        pmodeSpinner                   matlab.ui.control.Spinner
        CentreEditFieldLabel           matlab.ui.control.Label
        CentreEditField                matlab.ui.control.EditField
        AspectSpinnerLabel             matlab.ui.control.Label
        AspectSpinner                  matlab.ui.control.Spinner
        ScaleSpinnerLabel              matlab.ui.control.Label
        ScaleSpinner                   matlab.ui.control.Spinner
        mmodeSpinnerLabel              matlab.ui.control.Label
        mmodeSpinner                   matlab.ui.control.Spinner
        ParityDropDownLabel            matlab.ui.control.Label
        ParityDropDown                 matlab.ui.control.DropDown
        ElipticitySpinnerLabel         matlab.ui.control.Label
        ElipticitySpinner              matlab.ui.control.Spinner
        UIAxes                         matlab.ui.control.UIAxes
        UpdateDeviceCheckBox           matlab.ui.control.CheckBox
        EnableDisplayCheckBox          matlab.ui.control.CheckBox
        DisplayDropDownLabel           matlab.ui.control.Label
        DisplayDropDown                matlab.ui.control.DropDown
        DisplayVariableEditFieldLabel  matlab.ui.control.Label
        DisplayVariableEditField       matlab.ui.control.EditField
        DeviceDropDownLabel            matlab.ui.control.Label
        DeviceDropDown                 matlab.ui.control.DropDown
    end


    methods (Access = public)
    
        function updateView(app)
            % Redraw the main display
            app.patternValueChanged([]);
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Populate the display device field
            otslm.ui.support.populateDeviceList(app.DeviceDropDown);
            
            % Generate the initial pattern
            app.patternValueChanged([]);
        end

        % Value changed function: AngledegSpinner, AspectSpinner, 
        % CentreEditField, DeviceDropDown, DisplayDropDown, 
        % DisplayVariableEditField, ElipticitySpinner, 
        % EnableDisplayCheckBox, NameEditField, OutputTypeDropDown, 
        % ParityDropDown, ScaleSpinner, SizeEditField, 
        % UpdateDeviceCheckBox, mmodeSpinner
        function patternValueChanged(app, event)
            
            % Get the UI fileds for generating the pattern
            name = app.NameEditField.Value;
            sz = evalin('base', ['[', app.SizeEditField.Value, ']']);
            ptype = app.OutputTypeDropDown.Value;
            mmode = app.mmodeSpinner.Value;
            pmode = app.pmodeSpinner.Value;
            parity = strcmpi(app.ParityDropDown.Value, 'even');
            elipticity = app.ElipticitySpinner.Value;
            scale = app.ScaleSpinner.Value;
            angle_deg = app.AngledegSpinner.Value;
            aspect = app.AspectSpinner.Value;
            centre = evalin('base', ['[', app.CentreEditField.Value, ']']);
            
            if mod(mmode, 2) ~= mod(pmode, 2)
                mmode = mmode + 1;
                warning('Modes must have same parity');
            end
            
            % Generate the pattern
            [phase, amplitude] = otslm.simple.igmode(sz, parity, pmode, mmode, elipticity, ...
                'centre', centre, 'angle_deg', angle_deg, ...
                'scale', scale, 'aspect', aspect);
            
            % Offload to the base class (sort of...)
            otslm.ui.support.complexPatternValueChanged(name, phase, amplitude, ptype, ...
                app.DeviceDropDown.Value, app.UpdateDeviceCheckBox.Value, ...
                app.EnableDisplayCheckBox.Value, app.UIAxes, ...
                app.DisplayDropDown.Value, app.DisplayVariableEditField.Value);
            
        end

        % Value changed function: pmodeSpinner
        function pmodeSpinnerValueChanged(app, event)
            app.mmodeSpinner.Limits(2) = app.pmodeSpinner.Value;
            app.patternValueChanged(event);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.InceGaussianUIFigure = uifigure;
            app.InceGaussianUIFigure.Name = 'Ince-Gaussian';
            app.Panel = uipanel(app.InceGaussianUIFigure);
            app.SizeEditFieldLabel = uilabel(app.Panel);
            app.SizeEditFieldLabel.Text = 'Size';
            app.SizeEditField = uieditfield(app.Panel, 'text');
            app.SizeEditField.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.SizeEditField.Value = '256,256';
            app.NameEditFieldLabel = uilabel(app.Panel);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.Panel, 'text');
            app.NameEditField.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.OutputTypeDropDownLabel = uilabel(app.Panel);
            app.OutputTypeDropDownLabel.Text = 'Output Type';
            app.OutputTypeDropDown = uidropdown(app.Panel);
            app.OutputTypeDropDown.Items = {'Phase', 'Amplitude', 'Complex'};
            app.OutputTypeDropDown.ItemsData = {'phase', 'amplitude', 'complex'};
            app.OutputTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.OutputTypeDropDown.Value = 'phase';
            app.AngledegSpinnerLabel = uilabel(app.Panel);
            app.AngledegSpinnerLabel.Text = 'Angle (deg)';
            app.AngledegSpinner = uispinner(app.Panel);
            app.AngledegSpinner.Step = 5;
            app.AngledegSpinner.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.pmodeSpinnerLabel = uilabel(app.Panel);
            app.pmodeSpinnerLabel.Text = 'p mode';
            app.pmodeSpinner = uispinner(app.Panel);
            app.pmodeSpinner.Limits = [0 Inf];
            app.pmodeSpinner.RoundFractionalValues = 'on';
            app.pmodeSpinner.ValueDisplayFormat = '%.0f';
            app.pmodeSpinner.ValueChangedFcn = createCallbackFcn(app, @pmodeSpinnerValueChanged, true);
            app.pmodeSpinner.Value = 3;
            app.CentreEditFieldLabel = uilabel(app.Panel);
            app.CentreEditFieldLabel.Text = 'Centre';
            app.CentreEditField = uieditfield(app.Panel, 'text');
            app.CentreEditField.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.CentreEditField.Value = '128, 128';
            app.AspectSpinnerLabel = uilabel(app.Panel);
            app.AspectSpinnerLabel.Text = 'Aspect';
            app.AspectSpinner = uispinner(app.Panel);
            app.AspectSpinner.Step = 0.1;
            app.AspectSpinner.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.AspectSpinner.Value = 1;
            app.ScaleSpinnerLabel = uilabel(app.Panel);
            app.ScaleSpinnerLabel.Text = 'Scale';
            app.ScaleSpinner = uispinner(app.Panel);
            app.ScaleSpinner.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.ScaleSpinner.Value = 20;
            app.mmodeSpinnerLabel = uilabel(app.Panel);
            app.mmodeSpinnerLabel.Text = 'm mode';
            app.mmodeSpinner = uispinner(app.Panel);
            app.mmodeSpinner.Limits = [0 3];
            app.mmodeSpinner.RoundFractionalValues = 'on';
            app.mmodeSpinner.ValueDisplayFormat = '%.0f';
            app.mmodeSpinner.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.mmodeSpinner.Value = 1;
            app.ParityDropDownLabel = uilabel(app.Panel);
            app.ParityDropDownLabel.Text = 'Parity';
            app.ParityDropDown = uidropdown(app.Panel);
            app.ParityDropDown.Items = {'Even', 'Odd'};
            app.ParityDropDown.ItemsData = {'even', 'odd'};
            app.ParityDropDown.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.ParityDropDown.Value = 'even';
            app.ElipticitySpinnerLabel = uilabel(app.Panel);
            app.ElipticitySpinnerLabel.Text = 'Elipticity';
            app.ElipticitySpinner = uispinner(app.Panel);
            app.ElipticitySpinner.Step = 0.1;
            app.ElipticitySpinner.LowerLimitInclusive = 'off';
            app.ElipticitySpinner.Limits = [0 Inf];
            app.ElipticitySpinner.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.ElipticitySpinner.Value = 1;
            app.UIAxes = uiaxes(app.InceGaussianUIFigure);
            app.UpdateDeviceCheckBox = uicheckbox(app.InceGaussianUIFigure);
            app.UpdateDeviceCheckBox.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.UpdateDeviceCheckBox.Text = 'Update Device';
            app.EnableDisplayCheckBox = uicheckbox(app.InceGaussianUIFigure);
            app.EnableDisplayCheckBox.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.EnableDisplayCheckBox.Text = 'Enable Display';
            app.EnableDisplayCheckBox.Value = true;
            app.DisplayDropDownLabel = uilabel(app.InceGaussianUIFigure);
            app.DisplayDropDownLabel.Text = 'Display';
            app.DisplayDropDown = uidropdown(app.InceGaussianUIFigure);
            app.DisplayDropDown.Items = {'Phase mask', 'Device image', 'Simulated farfield', 'Raw pattern'};
            app.DisplayDropDown.ItemsData = {'phase', 'device', 'farfield', 'raw'};
            app.DisplayDropDown.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.DisplayDropDown.Value = 'phase';
            app.DisplayVariableEditFieldLabel = uilabel(app.InceGaussianUIFigure);
            app.DisplayVariableEditFieldLabel.Text = 'Display Variable';
            app.DisplayVariableEditField = uieditfield(app.InceGaussianUIFigure, 'text');
            app.DisplayVariableEditField.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.DeviceDropDownLabel = uilabel(app.InceGaussianUIFigure);
            app.DeviceDropDownLabel.Text = 'Device';
            app.DeviceDropDown = uidropdown(app.InceGaussianUIFigure);
            app.DeviceDropDown.Items = {};
            app.DeviceDropDown.Editable = 'on';
            app.DeviceDropDown.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.DeviceDropDown.Value = {};
        end
    end

    methods (Access = public)

        % Construct app
        function app = igmode

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.InceGaussianUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.InceGaussianUIFigure)
        end
    end
end