classdef linear < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LinearGratingUIFigure          matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        DisplayDropDownLabel           matlab.ui.control.Label
        DisplayDropDown                matlab.ui.control.DropDown
        SizeEditFieldLabel             matlab.ui.control.Label
        SizeEditField                  matlab.ui.control.EditField
        UpdateDeviceCheckBox           matlab.ui.control.CheckBox
        NameEditFieldLabel             matlab.ui.control.Label
        NameEditField                  matlab.ui.control.EditField
        EnableDisplayCheckBox          matlab.ui.control.CheckBox
        DisplayVariableEditFieldLabel  matlab.ui.control.Label
        DisplayVariableEditField       matlab.ui.control.EditField
        SpacingSpinnerLabel            matlab.ui.control.Label
        SpacingSpinner                 matlab.ui.control.Spinner
        AngledegSpinnerLabel           matlab.ui.control.Label
        AngledegSpinner                matlab.ui.control.Spinner
        OffsetSpinnerLabel             matlab.ui.control.Label
        OffsetSpinner                  matlab.ui.control.Spinner
        CentreEditFieldLabel           matlab.ui.control.Label
        CentreEditField                matlab.ui.control.EditField
        DeviceDropDownLabel            matlab.ui.control.Label
        DeviceDropDown                 matlab.ui.control.DropDown
    end


    methods (Access = public)
    
        function updateView(app)
            % Redraw the main display
            app.patternValueChanged([]);
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Populate the display device field
            otslm.ui.support.populateDeviceList(app.DeviceDropDown);
            
            % Generate the initial pattern
            app.patternValueChanged([]);
        end

        % Value changed function: AngledegSpinner, CentreEditField, 
        % DeviceDropDown, DisplayDropDown, DisplayVariableEditField, 
        % EnableDisplayCheckBox, NameEditField, OffsetSpinner, 
        % SizeEditField, SpacingSpinner, UpdateDeviceCheckBox
        function patternValueChanged(app, event)
            
            % Get the UI fileds for generating the pattern
            name = app.NameEditField.Value;
            sz = evalin('base', ['[', app.SizeEditField.Value, ']']);
            spacing = app.SpacingSpinner.Value;
            angle_deg = app.AngledegSpinner.Value;
            offset = app.OffsetSpinner.Value;
            centre = evalin('base', ['[', app.CentreEditField.Value, ']']);
            
            % Generate the pattern
            pattern = otslm.simple.linear(sz, spacing, ...
                'centre', centre, 'angle_deg', angle_deg);
            pattern = pattern + offset;
            
            % Offload to the base class (sort of...)
            otslm.ui.support.simplePatternValueChanged(name, pattern, ...
                app.DeviceDropDown.Value, app.UpdateDeviceCheckBox.Value, ...
                app.EnableDisplayCheckBox.Value, app.UIAxes, ...
                app.DisplayDropDown.Value, app.DisplayVariableEditField.Value);
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LinearGratingUIFigure = uifigure;
            app.LinearGratingUIFigure.Name = 'Linear Grating';
            app.UIAxes = uiaxes(app.LinearGratingUIFigure);
            app.DisplayDropDownLabel = uilabel(app.LinearGratingUIFigure);
            app.DisplayDropDownLabel.Text = 'Display';
            app.DisplayDropDown = uidropdown(app.LinearGratingUIFigure);
            app.DisplayDropDown.Items = {'Phase mask', 'Device image', 'Simulated farfield', 'Raw phase mask'};
            app.DisplayDropDown.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.DisplayDropDown.Value = 'Phase mask';
            app.SizeEditFieldLabel = uilabel(app.LinearGratingUIFigure);
            app.SizeEditFieldLabel.Text = 'Size';
            app.SizeEditField = uieditfield(app.LinearGratingUIFigure, 'text');
            app.SizeEditField.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.SizeEditField.Value = '256,256';
            app.UpdateDeviceCheckBox = uicheckbox(app.LinearGratingUIFigure);
            app.UpdateDeviceCheckBox.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.UpdateDeviceCheckBox.Text = 'Update Device';
            app.NameEditFieldLabel = uilabel(app.LinearGratingUIFigure);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.LinearGratingUIFigure, 'text');
            app.NameEditField.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.EnableDisplayCheckBox = uicheckbox(app.LinearGratingUIFigure);
            app.EnableDisplayCheckBox.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.EnableDisplayCheckBox.Text = 'Enable Display';
            app.EnableDisplayCheckBox.Value = true;
            app.DisplayVariableEditFieldLabel = uilabel(app.LinearGratingUIFigure);
            app.DisplayVariableEditFieldLabel.Text = 'Display Variable';
            app.DisplayVariableEditField = uieditfield(app.LinearGratingUIFigure, 'text');
            app.DisplayVariableEditField.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.SpacingSpinnerLabel = uilabel(app.LinearGratingUIFigure);
            app.SpacingSpinnerLabel.Text = 'Spacing';
            app.SpacingSpinner = uispinner(app.LinearGratingUIFigure);
            app.SpacingSpinner.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.SpacingSpinner.Value = 10;
            app.AngledegSpinnerLabel = uilabel(app.LinearGratingUIFigure);
            app.AngledegSpinnerLabel.Text = 'Angle (deg)';
            app.AngledegSpinner = uispinner(app.LinearGratingUIFigure);
            app.AngledegSpinner.Step = 5;
            app.AngledegSpinner.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.OffsetSpinnerLabel = uilabel(app.LinearGratingUIFigure);
            app.OffsetSpinnerLabel.Text = 'Offset';
            app.OffsetSpinner = uispinner(app.LinearGratingUIFigure);
            app.OffsetSpinner.Step = 0.05;
            app.OffsetSpinner.Limits = [-1 1];
            app.OffsetSpinner.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.CentreEditFieldLabel = uilabel(app.LinearGratingUIFigure);
            app.CentreEditFieldLabel.Text = 'Centre';
            app.CentreEditField = uieditfield(app.LinearGratingUIFigure, 'text');
            app.CentreEditField.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.CentreEditField.Value = '1,1';
            app.DeviceDropDownLabel = uilabel(app.LinearGratingUIFigure);
            app.DeviceDropDownLabel.Text = 'Device';
            app.DeviceDropDown = uidropdown(app.LinearGratingUIFigure);
            app.DeviceDropDown.Items = {};
            app.DeviceDropDown.Editable = 'on';
            app.DeviceDropDown.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.DeviceDropDown.Value = {};
        end
    end

    methods (Access = public)

        % Construct app
        function app = linear

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LinearGratingUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LinearGratingUIFigure)
        end
    end
end