classdef Dither < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DitherUIFigure                 matlab.ui.Figure
        Panel                          matlab.ui.container.Panel
        NameEditFieldLabel             matlab.ui.control.Label
        NameEditField                  matlab.ui.control.EditField
        MethodDropDownLabel            matlab.ui.control.Label
        MethodDropDown                 matlab.ui.control.DropDown
        LevelSpinnerLabel              matlab.ui.control.Label
        LevelSpinner                   matlab.ui.control.Spinner
        InputDropDownLabel             matlab.ui.control.Label
        InputDropDown                  matlab.ui.control.DropDown
        UIAxes                         matlab.ui.control.UIAxes
        UpdateDeviceCheckBox           matlab.ui.control.CheckBox
        EnableDisplayCheckBox          matlab.ui.control.CheckBox
        DisplayDropDownLabel           matlab.ui.control.Label
        DisplayDropDown                matlab.ui.control.DropDown
        DeviceEditFieldLabel           matlab.ui.control.Label
        DeviceEditField                matlab.ui.control.EditField
        DisplayVariableEditFieldLabel  matlab.ui.control.Label
        DisplayVariableEditField       matlab.ui.control.EditField
    end


    properties (Access = private)
        oldImages;      % Old images from last watch
        watchTimer;     % Timer to watch if input images change
    end

    methods (Access = private)
    
        function checkImagesChanged(app, timer, event)
            
            % Get latest images
            input = app.InputDropDown.Value;
            if ~isempty(input)
                input = evalin('base', input);
            end
            
            % If images have changed, update the display
            if ~all(input == app.oldImages)
                app.patternValueChanged([]);
            end
        end
        
    end
    
    methods (Access = public)
        
        function updateView(app)
            % Redraw the main display
            app.calculateData([]);
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % Populate input array with variables
            app.InputDropDown.Items = evalin('base', 'who');
            app.InputDropDown.Value = '';
            
            app.patternValueChanged([]);
            
            % Setup a timer to watch the images
            app.watchTimer = timer('StartDelay', 0, 'Period', 0.1, 'TasksToExecute', Inf, ...
                'ExecutionMode', 'fixedRate', 'BusyMode', 'drop');
            app.watchTimer.TimerFcn = @app.checkImagesChanged;
            start(app.watchTimer);
        end

        % Value changed function: DeviceEditField, DisplayDropDown, 
        % DisplayVariableEditField, EnableDisplayCheckBox, 
        % InputDropDown, LevelSpinner, MethodDropDown, NameEditField, 
        % UpdateDeviceCheckBox
        function patternValueChanged(app, event)
            
            % Get the UI fileds for generating the pattern
            name = app.NameEditField.Value;
            inputname = app.InputDropDown.Value;
            method = app.MethodDropDown.Value;
            level = app.LevelSpinner.Value;
            
            % Get the input image
            if ~isempty(inputname)
                input = evalin('base', inputname);
            else
                input = [];
            end
            app.oldImages = input;
            
            % Generate the pattern
            pattern = otslm.tools.dither(input, level, 'method', method);
            
            % Save the pattern in the base workspace
            if ~isempty(name)
                if ~isvarname(name)
                    warning('Name must be valid variable name');
                end
                
                assignin('base', name, pattern);
            end
            
            % Display image on the device
            slm = [];
            sname = app.DeviceEditField.Value;
            if ~isempty(sname)
                if ~isvarname(sname)
                    warning('Invalid device name specified');
                end
                slm = evalin('base', sname);
            end
            if app.UpdateDeviceCheckBox.Value && ~isempty(slm)
                spattern = otslm.tools.finalize(pattern, 'colormap', 'pmpi');
                slm.show(spattern);
            end
            
            % Generate display pattern
            if app.EnableDisplayCheckBox.Value
                
                % Generate the pattern to display
                dpattern = [];
                switch app.DisplayDropDown.Value
                    case 'Phase mask'
                        dpattern = otslm.tools.finalize(pattern*0.5, 'colormap', 'pmpi');
                    case 'Raw phase mask'
                        dpattern = pattern;
                    case 'Device image'
                        if ~isempty(slm)
                            dpattern = slm.view(pattern);
                        else
                            warning('Device not setup');
                        end
                    case 'Simulated farfield'
                        dpattern = otslm.tools.finalize(pattern*0.5, 'colormap', 'pmpi');
                        dpattern = otslm.tools.visualise(dpattern, 'method', 'fft', 'trim_padding', true, ...
                            'padding', ceil(size(dpattern)/2), 'incident', ones(size(dpattern)));
                        dpattern = abs(dpattern).^2;
                    otherwise
                        error('Invalid drop down option selected');
                end
                
                % Show the pattern in the window
                if ~isempty(dpattern)
                    imagesc(app.UIAxes, dpattern);
                    axis(app.UIAxes, 'image');
                    set(app.UIAxes, 'xtick', [], 'xticklabel', []);
                    set(app.UIAxes, 'ytick', [], 'yticklabel', []);
                end
                
                % Write pattern to workspace
                if ~isempty(app.DisplayVariableEditField.Value)
                    dname = app.DisplayVariableEditField.Value;
                    if ~isvarname(dname)
                        warning('Display variable must be valid variable name');
                    end
                    
                    assignin('base', dname, dpattern);
                end
            end
            
        end

        % Close request function: DitherUIFigure
        function DitherUIFigureCloseRequest(app, event)
            stop(app.watchTimer);
            delete(app.watchTimer);
            delete(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DitherUIFigure = uifigure;
            app.DitherUIFigure.Name = 'Dither';
            app.DitherUIFigure.CloseRequestFcn = createCallbackFcn(app, @DitherUIFigureCloseRequest, true);
            app.Panel = uipanel(app.DitherUIFigure);
            app.NameEditFieldLabel = uilabel(app.Panel);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.Panel, 'text');
            app.NameEditField.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.MethodDropDownLabel = uilabel(app.Panel);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.Panel);
            app.MethodDropDown.Items = {'Matlab Dither', 'Floyd', 'Random', 'Threshold'};
            app.MethodDropDown.ItemsData = {'mdither', 'floyd', 'random', 'threshold'};
            app.MethodDropDown.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.MethodDropDown.Value = 'floyd';
            app.LevelSpinnerLabel = uilabel(app.Panel);
            app.LevelSpinnerLabel.Text = 'Level';
            app.LevelSpinner = uispinner(app.Panel);
            app.LevelSpinner.Step = 0.1;
            app.LevelSpinner.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.LevelSpinner.Value = 0.5;
            app.InputDropDownLabel = uilabel(app.Panel);
            app.InputDropDownLabel.Text = 'Input';
            app.InputDropDown = uidropdown(app.Panel);
            app.InputDropDown.Items = {};
            app.InputDropDown.Editable = 'on';
            app.InputDropDown.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.InputDropDown.Value = {};
            app.UIAxes = uiaxes(app.DitherUIFigure);
            app.UpdateDeviceCheckBox = uicheckbox(app.DitherUIFigure);
            app.UpdateDeviceCheckBox.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.UpdateDeviceCheckBox.Text = 'Update Device';
            app.EnableDisplayCheckBox = uicheckbox(app.DitherUIFigure);
            app.EnableDisplayCheckBox.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.EnableDisplayCheckBox.Text = 'Enable Display';
            app.EnableDisplayCheckBox.Value = true;
            app.DisplayDropDownLabel = uilabel(app.DitherUIFigure);
            app.DisplayDropDownLabel.Text = 'Display';
            app.DisplayDropDown = uidropdown(app.DitherUIFigure);
            app.DisplayDropDown.Items = {'Phase mask', 'Device image', 'Simulated farfield', 'Raw phase mask'};
            app.DisplayDropDown.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.DisplayDropDown.Value = 'Phase mask';
            app.DeviceEditFieldLabel = uilabel(app.DitherUIFigure);
            app.DeviceEditFieldLabel.Text = 'Device';
            app.DeviceEditField = uieditfield(app.DitherUIFigure, 'text');
            app.DeviceEditField.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.DisplayVariableEditFieldLabel = uilabel(app.DitherUIFigure);
            app.DisplayVariableEditFieldLabel.Text = 'Display Variable';
            app.DisplayVariableEditField = uieditfield(app.DitherUIFigure, 'text');
            app.DisplayVariableEditField.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = Dither

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DitherUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DitherUIFigure)
        end
    end
end