classdef MaskRegion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MaskRegionsUIFigure            matlab.ui.Figure
        Panel                          matlab.ui.container.Panel
        TabGroup                       matlab.ui.container.TabGroup
        RegionTab                      matlab.ui.container.Tab
        MaskDropDownLabel              matlab.ui.control.Label
        MaskDropDown                   matlab.ui.control.DropDown
        PatternDropDownLabel           matlab.ui.control.Label
        PatternDropDown                matlab.ui.control.DropDown
        Size1SpinnerLabel              matlab.ui.control.Label
        Size1Spinner                   matlab.ui.control.Spinner
        Size2SpinnerLabel              matlab.ui.control.Label
        Size2Spinner                   matlab.ui.control.Spinner
        ShapeDropDownLabel             matlab.ui.control.Label
        ShapeDropDown                  matlab.ui.control.DropDown
        PositionEditFieldLabel         matlab.ui.control.Label
        PositionEditField              matlab.ui.control.EditField
        AddRegionButton                matlab.ui.control.Button
        RemoveRegionButton             matlab.ui.control.Button
        NameEditFieldLabel             matlab.ui.control.Label
        NameEditField                  matlab.ui.control.EditField
        BackgroundDropDownLabel        matlab.ui.control.Label
        BackgroundDropDown             matlab.ui.control.DropDown
        UIAxes                         matlab.ui.control.UIAxes
        UpdateDeviceCheckBox           matlab.ui.control.CheckBox
        EnableDisplayCheckBox          matlab.ui.control.CheckBox
        DisplayDropDownLabel           matlab.ui.control.Label
        DisplayDropDown                matlab.ui.control.DropDown
        DisplayVariableEditFieldLabel  matlab.ui.control.Label
        DisplayVariableEditField       matlab.ui.control.EditField
        DeviceDropDownLabel            matlab.ui.control.Label
        DeviceDropDown                 matlab.ui.control.DropDown
    end


    properties (Access = private)
        oldImages;          % Old background image data
        oldMasks;           % Old mask images from tabs
        oldPatterns;        % Old pattern images from tabs
        
        watchTimer;     % Timer to watch if input images change
    end

    methods (Access = private)
        
        function [patterns, masks] = getPatternsAndMasks(app, silent)
            % Get patterns and masks from tab-group
            
            if nargin < 2
                silent = false;
            end
            
            patterns = {};
            masks = {};
            
            for ii = 1:length(app.TabGroup.Children)
                tab = app.TabGroup.Children(ii);
                
                entry = otslm.ui.support.findTabUserdata(tab, {'mask', 'pattern'});
                
                patterns{end+1} = otslm.ui.support.getImageOrNone(entry.pattern.Value, silent);
                masks{end+1} = otslm.ui.support.getImageOrNone(entry.mask.Value, silent);
            end
        end
    
        function checkImagesChanged(app, timer, event)
            
            % Get new images
            background = otslm.ui.support.getImageOrNone(app.BackgroundDropDown.Value, true);
            [patterns, masks] = app.getPatternsAndMasks(true);
            
            % Check for changes
            changed1 = otslm.ui.support.checkImagesChanged({background}, {app.oldImages});
            changed2 = otslm.ui.support.checkImagesChanged(patterns, app.oldPatterns);
            changed3 = otslm.ui.support.checkImagesChanged(masks, app.oldMasks);
            
            % If we changed, update the display
            if changed1 || changed2 || changed3
                app.patternValueChanged([]);
            end
        end
        
    end
    
    methods (Access = public)
        
        function updateView(app)
            % Redraw the main display
            app.calculateData([]);
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Hide the template tab
            app.RegionTab.Parent = [];
            app.MaskDropDown.UserData = 'mask';
            app.PatternDropDown.UserData = 'pattern';
            app.Size1Spinner.UserData = 'size1';
            app.Size2Spinner.UserData = 'size2';
            app.ShapeDropDown.UserData = 'shape';
            app.PositionEditField.UserData = 'position';
            app.RemoveRegionButton.Enable = 'off';
            
            % Populate input array with variables
            varnames = evalin('base', 'who');
            app.MaskDropDown.Items = varnames;
            app.PatternDropDown.Items = varnames;
            app.BackgroundDropDown.Items = varnames;
            app.MaskDropDown.Value = '';
            app.PatternDropDown.Value = '';
            app.BackgroundDropDown.Value = '';
            
            % Populate the display device field
            otslm.ui.support.populateDeviceList(app.DeviceDropDown);
            
            % Generate the initial pattern
            app.patternValueChanged([]);
            
            % Setup a timer to watch the images
            app.watchTimer = timer('StartDelay', 0, 'Period', 0.1, 'TasksToExecute', Inf, ...
                'ExecutionMode', 'fixedRate', 'BusyMode', 'drop');
            app.watchTimer.TimerFcn = @app.checkImagesChanged;
            start(app.watchTimer);
        end

        % Button pushed function: RemoveRegionButton
        function RemoveRegionButtonPushed(app, event)
            % Remove the current region
            selectedTab = app.TabGroup.SelectedTab;
            if ~isempty(selectedTab)
                selectedTab.Parent = [];
                if isempty(app.TabGroup.Children)
                    app.RemoveRegionButton.Enable = 'off';
                end
            end
            app.patternValueChanged(event);
        end

        % Button pushed function: AddRegionButton
        function AddRegionButtonPushed(app, event)
            tab = copyobj(app.RegionTab, app.TabGroup);
            
            % Add callbacks to new object
            entry = otslm.ui.support.findTabUserdata(tab, ...
                {'mask', 'pattern', 'size1', 'size2', 'shape', 'position'});
            
            entry.mask.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            entry.pattern.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            entry.size1.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            entry.size2.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            entry.shape.ValueChangedFcn = createCallbackFcn(app, @ShapeDropDownValueChanged, true);
            entry.position.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            
            app.RemoveRegionButton.Enable = 'on';
            app.patternValueChanged(event);
        end

        % Value changed function: BackgroundDropDown, DeviceDropDown, 
        % DisplayDropDown, DisplayVariableEditField, 
        % EnableDisplayCheckBox, NameEditField, PositionEditField, 
        % UpdateDeviceCheckBox
        function patternValueChanged(app, event)
            
            % Get the UI fileds for generating the pattern
            name = app.NameEditField.Value;
            
            % Store last input images (for auto-update)
            app.oldImages = otslm.ui.support.getImageOrNone(app.BackgroundDropDown.Value);
            [app.oldPatterns, app.oldMasks] = app.getPatternsAndMasks();
            
            % Check we have work to do
            if isempty(app.oldImages)
                warning('Need background image');
                return;
            end
            
            % Get parameters from user interface
            appature_patterns = {};
            mask_patterns = {};
            locations = {};
            sizes = {};
            shapes = {};
            masks = {};
            for ii = 1:length(app.TabGroup.Children)
                tab = app.TabGroup.Children(ii);
                
                entry = otslm.ui.support.findTabUserdata(tab, ...
                    {'mask', 'pattern', 'size1', 'size2', 'shape', 'position'});
                
                if strcmpi(entry.shape.Value, 'custom')
                    cpattern = otslm.ui.support.getImageOrNone(entry.pattern.Value);
                    cmask = otslm.ui.support.getImageOrNone(entry.mask.Value);
                    
                    if isempty(cpattern) || isempty(cmask)
                        warning('skipping empty pattern');
                        continue;
                    end
                    
                    mask_patterns{end+1} = cpattern;
                    masks{end+1} = cmask;
                else
                    im = otslm.ui.support.getImageOrNone(entry.pattern.Value);
                    if isempty(im)
                        warning('skipping empty pattern');
                        continue;
                    end
                    
                    appature_patterns{end+1} = im;
                    
                    locations{end+1} = evalin('base', ['[', entry.position.Value, ']']);
                    if strcmpi(entry.size2.Enable, 'on')
                        sizes{end+1} = [entry.size1.Value, entry.size2.Value];
                    else
                        sizes{end+1} = entry.size1.Value;
                    end
                    shapes{end+1} = entry.shape.Value;
                        
                end
            end
            
            % Generate the pattern
            pattern = otslm.tools.mask_regions(app.oldImages, appature_patterns, ...
                locations, sizes, 'shape', shapes);
            
            % Add additional masked regions
            for ii = 1:length(masks)
                pattern(masks{ii}) = mask_patterns{ii}(masks{ii});
            end
            
            % Offload to the base class (sort of...)
            otslm.ui.support.simplePatternValueChanged(name, pattern, ...
                app.DeviceDropDown.Value, app.UpdateDeviceCheckBox.Value, ...
                app.EnableDisplayCheckBox.Value, app.UIAxes, ...
                app.DisplayDropDown.Value, app.DisplayVariableEditField.Value);
            
        end

        % Close request function: MaskRegionsUIFigure
        function MaskRegionsUIFigureCloseRequest(app, event)
            otslm.ui.support.cleanTimer(app.watchTimer);
            delete(app);
        end

        % Value changed function: ShapeDropDown
        function ShapeDropDownValueChanged(app, event)

            % Get the tab that invoked the event
            tab = event.Source.Parent;
            
            % Get the entries on this tab
            entry = otslm.ui.support.findTabUserdata(tab, ...
                {'mask', 'pattern', 'size1', 'size2', 'shape', 'position'});
            
            switch entry.shape.Value
                case 'circle'
                    entry.size1.Enable = 'on';
                    entry.size2.Enable = 'off';
                    entry.position.Enable = 'on';
                    entry.mask.Enable = 'off';
                case 'square'
                    entry.size1.Enable = 'on';
                    entry.size2.Enable = 'off';
                    entry.position.Enable = 'on';
                    entry.mask.Enable = 'off';
                case 'rect'
                    entry.size1.Enable = 'on';
                    entry.size2.Enable = 'on';
                    entry.position.Enable = 'on';
                    entry.mask.Enable = 'off';
                case 'ring'
                    entry.size1.Enable = 'on';
                    entry.size2.Enable = 'on';
                    entry.position.Enable = 'on';
                    entry.mask.Enable = 'off';
                case 'custom'
                    entry.size1.Enable = 'off';
                    entry.size2.Enable = 'off';
                    entry.position.Enable = 'off';
                    entry.mask.Enable = 'on';
                otherwise
                    error('Invalid drop down value');
            end
            
            % Continue processing as usual
            app.patternValueChanged(event);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MaskRegionsUIFigure = uifigure;
            app.MaskRegionsUIFigure.Name = 'Mask Regions';
            app.MaskRegionsUIFigure.CloseRequestFcn = createCallbackFcn(app, @MaskRegionsUIFigureCloseRequest, true);
            app.Panel = uipanel(app.MaskRegionsUIFigure);
            app.TabGroup = uitabgroup(app.Panel);
            app.RegionTab = uitab(app.TabGroup);
            app.RegionTab.Title = 'Region';
            app.MaskDropDownLabel = uilabel(app.RegionTab);
            app.MaskDropDownLabel.Enable = 'off';
            app.MaskDropDownLabel.Text = 'Mask';
            app.MaskDropDown = uidropdown(app.RegionTab);
            app.MaskDropDown.Items = {};
            app.MaskDropDown.Editable = 'on';
            app.MaskDropDown.Enable = 'off';
            app.MaskDropDown.Value = {};
            app.PatternDropDownLabel = uilabel(app.RegionTab);
            app.PatternDropDownLabel.Text = 'Pattern';
            app.PatternDropDown = uidropdown(app.RegionTab);
            app.PatternDropDown.Items = {};
            app.PatternDropDown.Editable = 'on';
            app.PatternDropDown.Value = {};
            app.Size1SpinnerLabel = uilabel(app.RegionTab);
            app.Size1SpinnerLabel.Text = 'Size 1';
            app.Size1Spinner = uispinner(app.RegionTab);
            app.Size1Spinner.Value = 10;
            app.Size2SpinnerLabel = uilabel(app.RegionTab);
            app.Size2SpinnerLabel.Enable = 'off';
            app.Size2SpinnerLabel.Text = 'Size 2';
            app.Size2Spinner = uispinner(app.RegionTab);
            app.Size2Spinner.Enable = 'off';
            app.Size2Spinner.Value = 10;
            app.ShapeDropDownLabel = uilabel(app.RegionTab);
            app.ShapeDropDownLabel.Text = 'Shape';
            app.ShapeDropDown = uidropdown(app.RegionTab);
            app.ShapeDropDown.Items = {'Circle', 'Square', 'Rectangle', 'Ring', 'Custom'};
            app.ShapeDropDown.ItemsData = {'circle', 'square', 'rect', 'ring', 'custom'};
            app.ShapeDropDown.ValueChangedFcn = createCallbackFcn(app, @ShapeDropDownValueChanged, true);
            app.ShapeDropDown.Value = 'circle';
            app.PositionEditFieldLabel = uilabel(app.RegionTab);
            app.PositionEditFieldLabel.Text = 'Position';
            app.PositionEditField = uieditfield(app.RegionTab, 'text');
            app.PositionEditField.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.PositionEditField.Value = '100,100';
            app.AddRegionButton = uibutton(app.Panel, 'push');
            app.AddRegionButton.ButtonPushedFcn = createCallbackFcn(app, @AddRegionButtonPushed, true);
            app.AddRegionButton.Text = 'Add Region';
            app.RemoveRegionButton = uibutton(app.Panel, 'push');
            app.RemoveRegionButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveRegionButtonPushed, true);
            app.RemoveRegionButton.Text = 'Remove Region';
            app.NameEditFieldLabel = uilabel(app.Panel);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.Panel, 'text');
            app.NameEditField.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.BackgroundDropDownLabel = uilabel(app.Panel);
            app.BackgroundDropDownLabel.Text = 'Background';
            app.BackgroundDropDown = uidropdown(app.Panel);
            app.BackgroundDropDown.Items = {};
            app.BackgroundDropDown.Editable = 'on';
            app.BackgroundDropDown.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.BackgroundDropDown.Value = {};
            app.UIAxes = uiaxes(app.MaskRegionsUIFigure);
            app.UpdateDeviceCheckBox = uicheckbox(app.MaskRegionsUIFigure);
            app.UpdateDeviceCheckBox.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.UpdateDeviceCheckBox.Text = 'Update Device';
            app.EnableDisplayCheckBox = uicheckbox(app.MaskRegionsUIFigure);
            app.EnableDisplayCheckBox.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.EnableDisplayCheckBox.Text = 'Enable Display';
            app.EnableDisplayCheckBox.Value = true;
            app.DisplayDropDownLabel = uilabel(app.MaskRegionsUIFigure);
            app.DisplayDropDownLabel.Text = 'Display';
            app.DisplayDropDown = uidropdown(app.MaskRegionsUIFigure);
            app.DisplayDropDown.Items = {'Phase mask', 'Device image', 'Simulated farfield', 'Raw phase mask'};
            app.DisplayDropDown.ItemsData = {'Phase mask', 'Device image', 'Simulated farfield', 'Raw phase mask'};
            app.DisplayDropDown.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.DisplayDropDown.Value = 'Phase mask';
            app.DisplayVariableEditFieldLabel = uilabel(app.MaskRegionsUIFigure);
            app.DisplayVariableEditFieldLabel.Text = 'Display Variable';
            app.DisplayVariableEditField = uieditfield(app.MaskRegionsUIFigure, 'text');
            app.DisplayVariableEditField.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.DeviceDropDownLabel = uilabel(app.MaskRegionsUIFigure);
            app.DeviceDropDownLabel.Text = 'Device';
            app.DeviceDropDown = uidropdown(app.MaskRegionsUIFigure);
            app.DeviceDropDown.Items = {};
            app.DeviceDropDown.Editable = 'on';
            app.DeviceDropDown.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.DeviceDropDown.Value = {};
        end
    end

    methods (Access = public)

        % Construct app
        function app = MaskRegion

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MaskRegionsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MaskRegionsUIFigure)
        end
    end
end