classdef SampleRegion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GenerateSampleRegionUIFigure   matlab.ui.Figure
        Panel                          matlab.ui.container.Panel
        TabGroup                       matlab.ui.container.TabGroup
        RegionTab                      matlab.ui.container.Tab
        PositionEditFieldLabel         matlab.ui.control.Label
        PositionEditField              matlab.ui.control.EditField
        TargetEditFieldLabel           matlab.ui.control.Label
        TargetEditField                matlab.ui.control.EditField
        FilterDropDownLabel            matlab.ui.control.Label
        FilterDropDown                 matlab.ui.control.DropDown
        FilterParamsEditFieldLabel     matlab.ui.control.Label
        FilterParamsEditField          matlab.ui.control.EditField
        RadiusEditFieldLabel           matlab.ui.control.Label
        RadiusEditField                matlab.ui.control.EditField
        AddRegionButton                matlab.ui.control.Button
        RemoveRegionButton             matlab.ui.control.Button
        NameEditFieldLabel             matlab.ui.control.Label
        NameEditField                  matlab.ui.control.EditField
        BackgroundDropDownLabel        matlab.ui.control.Label
        BackgroundDropDown             matlab.ui.control.DropDown
        SizeEditFieldLabel             matlab.ui.control.Label
        SizeEditField                  matlab.ui.control.EditField
        UIAxes                         matlab.ui.control.UIAxes
        UpdateDeviceCheckBox           matlab.ui.control.CheckBox
        EnableDisplayCheckBox          matlab.ui.control.CheckBox
        DisplayDropDownLabel           matlab.ui.control.Label
        DisplayDropDown                matlab.ui.control.DropDown
        DisplayVariableEditFieldLabel  matlab.ui.control.Label
        DisplayVariableEditField       matlab.ui.control.EditField
        DeviceDropDownLabel            matlab.ui.control.Label
        DeviceDropDown                 matlab.ui.control.DropDown
    end


    properties (Access = private)
        oldImages;          % Old background image data
        watchTimer;     % Timer to watch if input images change
    end

    methods (Access = private)
        
        function im = getBackgroudImgOrStr(app, silent)
            
            if nargin < 2
                silent = false;
            end
            
            % Get varname or string
            im = app.BackgroundDropDown.Value;
            
            % If string is not pre-defined string, assume its an image
            if ~any(strcmpi(im, app.BackgroundDropDown.ItemsData))
            
                % Get latest images
                im = otslm.ui.support.getImageOrNone(app.BackgroundDropDown.Value, silent);
                
                % Pre-scale the background image
                im = im .* 2*pi;
                
            end
        end
    
        function checkImagesChanged(app, timer, event)
            
            % Background image could be a char array (for pre-defined backgrounds)
            newImages = app.getBackgroudImgOrStr();
            
            % Check for changes
            changed = otslm.ui.support.checkImagesChanged({newImages}, {app.oldImages});
            
            % If we changed, update the display
            if changed
                app.patternValueChanged([]);
            end
        end
        
    end
    
    methods (Access = public)
        
        function updateView(app)
            % Redraw the main display
            app.calculateData([]);
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Hide the template tab
            app.RegionTab.Parent = [];
            app.PositionEditField.UserData = 'location';
            app.TargetEditField.UserData = 'target';
            app.RadiusEditField.UserData = 'radius';
            app.FilterDropDown.UserData = 'filter';
            app.FilterParamsEditField.UserData = 'filter_params';
            app.RemoveRegionButton.Enable = 'off';
            
            % Populate the display device field
            otslm.ui.support.populateDeviceList(app.DeviceDropDown);
            
            % Generate the initial pattern
            app.patternValueChanged([]);
            
            % Setup a timer to watch the images
            app.watchTimer = timer('StartDelay', 0, 'Period', 0.1, 'TasksToExecute', Inf, ...
                'ExecutionMode', 'fixedRate', 'BusyMode', 'drop');
            app.watchTimer.TimerFcn = @app.checkImagesChanged;
            start(app.watchTimer);
        end

        % Button pushed function: RemoveRegionButton
        function RemoveRegionButtonPushed(app, event)
            % Remove the current region
            selectedTab = app.TabGroup.SelectedTab;
            if ~isempty(selectedTab)
                selectedTab.Parent = [];
                if isempty(app.TabGroup.Children)
                    app.RemoveRegionButton.Enable = 'off';
                end
            end
            app.patternValueChanged(event);
        end

        % Button pushed function: AddRegionButton
        function AddRegionButtonPushed(app, event)
            tab = copyobj(app.RegionTab, app.TabGroup);
            
            % Add callbacks to new object
            entry = otslm.ui.support.findTabUserdata(tab, ...
                {'location', 'target', 'radius', 'filter', 'filter_params'});
            
            entry.location.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            entry.target.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            entry.radius.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            entry.filter.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            entry.filter_params.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            
            app.RemoveRegionButton.Enable = 'on';
            app.patternValueChanged(event);
        end

        % Value changed function: BackgroundDropDown, DeviceDropDown, 
        % DisplayDropDown, DisplayVariableEditField, 
        % EnableDisplayCheckBox, FilterDropDown, FilterParamsEditField, 
        % NameEditField, PositionEditField, RadiusEditField, 
        % SizeEditField, TargetEditField, UpdateDeviceCheckBox
        function patternValueChanged(app, event)
            
            % Get the UI fileds for generating the pattern
            name = app.NameEditField.Value;
            sz = evalin('base', ['[', app.SizeEditField.Value, ']']);
            
            % Get lates background image
            app.oldImages = app.getBackgroudImgOrStr();
            
            % Get parameters from user interface
            locations = {};
            detectors = {};
            amplitudes = {};
            radius = [];
            for ii = 1:length(app.TabGroup.Children)
                tab = app.TabGroup.Children(ii);
                
                entry = otslm.ui.support.findTabUserdata(tab, ...
                    {'location', 'target', 'radius', 'filter', 'filter_params'});
                
                locations{ii} = evalin('base', ['[', entry.location.Value, ']']);
                detectors{ii} = evalin('base', ['[', entry.target.Value, ']']);
                radius(ii) = evalin('base', ['[', entry.radius.Value, ']']);
                amplitudes{ii} = {entry.filter.Value};
                amplitudes{ii} = [amplitudes{ii}, evalin('base', ['{', entry.filter_params.Value, '}'])];
            end
            
            % Generate the pattern
            pattern = otslm.tools.sample_region(sz, locations, detectors, ...
                'background', app.oldImages, 'amplitude', amplitudes, ...
                'radii', radius);
            
            % Offload to the base class (sort of...)
            otslm.ui.support.simplePatternValueChanged(name, pattern, ...
                app.DeviceDropDown.Value, app.UpdateDeviceCheckBox.Value, ...
                app.EnableDisplayCheckBox.Value, app.UIAxes, ...
                app.DisplayDropDown.Value, app.DisplayVariableEditField.Value);
            
        end

        % Close request function: GenerateSampleRegionUIFigure
        function GenerateSampleRegionUIFigureCloseRequest(app, event)
            otslm.ui.support.cleanTimer(app.watchTimer);
            delete(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GenerateSampleRegionUIFigure = uifigure;
            app.GenerateSampleRegionUIFigure.Name = 'Generate Sample Region';
            app.GenerateSampleRegionUIFigure.CloseRequestFcn = createCallbackFcn(app, @GenerateSampleRegionUIFigureCloseRequest, true);
            app.Panel = uipanel(app.GenerateSampleRegionUIFigure);
            app.TabGroup = uitabgroup(app.Panel);
            app.RegionTab = uitab(app.TabGroup);
            app.RegionTab.Title = 'Region';
            app.PositionEditFieldLabel = uilabel(app.RegionTab);
            app.PositionEditFieldLabel.Text = 'Position';
            app.PositionEditField = uieditfield(app.RegionTab, 'text');
            app.PositionEditField.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.PositionEditField.Value = '100,100';
            app.TargetEditFieldLabel = uilabel(app.RegionTab);
            app.TargetEditFieldLabel.Text = 'Target';
            app.TargetEditField = uieditfield(app.RegionTab, 'text');
            app.TargetEditField.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.TargetEditField.Value = '10,10';
            app.FilterDropDownLabel = uilabel(app.RegionTab);
            app.FilterDropDownLabel.Text = 'Filter';
            app.FilterDropDown = uidropdown(app.RegionTab);
            app.FilterDropDown.Items = {'Step', 'Gaussian Dither', 'Gaussian Noise', 'Gaussian Scale'};
            app.FilterDropDown.ItemsData = {'step', 'gaussian_dither', 'gaussian_noise', 'gaussian_scale'};
            app.FilterDropDown.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.FilterDropDown.Value = 'step';
            app.FilterParamsEditFieldLabel = uilabel(app.RegionTab);
            app.FilterParamsEditFieldLabel.Text = 'Filter Params';
            app.FilterParamsEditField = uieditfield(app.RegionTab, 'text');
            app.FilterParamsEditField.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.RadiusEditFieldLabel = uilabel(app.RegionTab);
            app.RadiusEditFieldLabel.Text = 'Radius';
            app.RadiusEditField = uieditfield(app.RegionTab, 'text');
            app.RadiusEditField.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.RadiusEditField.Value = '10';
            app.AddRegionButton = uibutton(app.Panel, 'push');
            app.AddRegionButton.ButtonPushedFcn = createCallbackFcn(app, @AddRegionButtonPushed, true);
            app.AddRegionButton.Text = 'Add Region';
            app.RemoveRegionButton = uibutton(app.Panel, 'push');
            app.RemoveRegionButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveRegionButtonPushed, true);
            app.RemoveRegionButton.Text = 'Remove Region';
            app.NameEditFieldLabel = uilabel(app.Panel);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.Panel, 'text');
            app.NameEditField.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.BackgroundDropDownLabel = uilabel(app.Panel);
            app.BackgroundDropDownLabel.Text = 'Background';
            app.BackgroundDropDown = uidropdown(app.Panel);
            app.BackgroundDropDown.Items = {'Zero', 'NaN', 'Checker Board', 'Random', 'Random Binary'};
            app.BackgroundDropDown.ItemsData = {'zero', 'nan', 'checkerboard', 'random', 'randombin'};
            app.BackgroundDropDown.Editable = 'on';
            app.BackgroundDropDown.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.BackgroundDropDown.Value = 'zero';
            app.SizeEditFieldLabel = uilabel(app.Panel);
            app.SizeEditFieldLabel.Text = 'Size';
            app.SizeEditField = uieditfield(app.Panel, 'text');
            app.SizeEditField.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.SizeEditField.Value = '256, 256';
            app.UIAxes = uiaxes(app.GenerateSampleRegionUIFigure);
            app.UpdateDeviceCheckBox = uicheckbox(app.GenerateSampleRegionUIFigure);
            app.UpdateDeviceCheckBox.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.UpdateDeviceCheckBox.Text = 'Update Device';
            app.EnableDisplayCheckBox = uicheckbox(app.GenerateSampleRegionUIFigure);
            app.EnableDisplayCheckBox.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.EnableDisplayCheckBox.Text = 'Enable Display';
            app.EnableDisplayCheckBox.Value = true;
            app.DisplayDropDownLabel = uilabel(app.GenerateSampleRegionUIFigure);
            app.DisplayDropDownLabel.Text = 'Display';
            app.DisplayDropDown = uidropdown(app.GenerateSampleRegionUIFigure);
            app.DisplayDropDown.Items = {'Phase mask', 'Device image', 'Simulated farfield', 'Raw phase mask'};
            app.DisplayDropDown.ItemsData = {'Phase mask', 'Device image', 'Simulated farfield', 'Raw phase mask'};
            app.DisplayDropDown.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.DisplayDropDown.Value = 'Phase mask';
            app.DisplayVariableEditFieldLabel = uilabel(app.GenerateSampleRegionUIFigure);
            app.DisplayVariableEditFieldLabel.Text = 'Display Variable';
            app.DisplayVariableEditField = uieditfield(app.GenerateSampleRegionUIFigure, 'text');
            app.DisplayVariableEditField.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.DeviceDropDownLabel = uilabel(app.GenerateSampleRegionUIFigure);
            app.DeviceDropDownLabel.Text = 'Device';
            app.DeviceDropDown = uidropdown(app.GenerateSampleRegionUIFigure);
            app.DeviceDropDown.Items = {};
            app.DeviceDropDown.Editable = 'on';
            app.DeviceDropDown.ValueChangedFcn = createCallbackFcn(app, @patternValueChanged, true);
            app.DeviceDropDown.Value = {};
        end
    end

    methods (Access = public)

        % Construct app
        function app = SampleRegion

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GenerateSampleRegionUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GenerateSampleRegionUIFigure)
        end
    end
end